classdef QuadSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AirspeedIndicatorLabel          matlab.ui.control.Label
        speed_meter                     Aero.ui.control.AirspeedIndicator
        ArtificialHorizonLabel          matlab.ui.control.Label
        horizon_meter                   Aero.ui.control.ArtificialHorizon
        HeadingIndicatorLabel           matlab.ui.control.Label
        heading_meter                   Aero.ui.control.HeadingIndicator
        Panel                           matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        mkgLabel                        matlab.ui.control.Label
        m                               matlab.ui.control.EditField
        gkgs2Label                      matlab.ui.control.Label
        g                               matlab.ui.control.EditField
        LmLabel                         matlab.ui.control.Label
        L                               matlab.ui.control.EditField
        IxxLabel                        matlab.ui.control.Label
        Ixx                             matlab.ui.control.EditField
        IyyLabel                        matlab.ui.control.Label
        Iyy                             matlab.ui.control.EditField
        IzzLabel                        matlab.ui.control.Label
        Izz                             matlab.ui.control.EditField
        Tab_3                           matlab.ui.container.Tab
        k_FEditFieldLabel               matlab.ui.control.Label
        k_F                             matlab.ui.control.EditField
        k_MEditFieldLabel               matlab.ui.control.Label
        k_M                             matlab.ui.control.EditField
        k_mEditFieldLabel               matlab.ui.control.Label
        k_m                             matlab.ui.control.EditField
        Tab_4                           matlab.ui.container.Tab
        kp_xEditFieldLabel              matlab.ui.control.Label
        kp_x                            matlab.ui.control.EditField
        ki_xEditFieldLabel              matlab.ui.control.Label
        ki_x                            matlab.ui.control.EditField
        kd_xEditFieldLabel              matlab.ui.control.Label
        kd_x                            matlab.ui.control.EditField
        kp_yEditFieldLabel              matlab.ui.control.Label
        kp_y                            matlab.ui.control.EditField
        kd_yEditFieldLabel              matlab.ui.control.Label
        kd_y                            matlab.ui.control.EditField
        kp_zEditFieldLabel              matlab.ui.control.Label
        kp_z                            matlab.ui.control.EditField
        kd_zEditFieldLabel              matlab.ui.control.Label
        kd_z                            matlab.ui.control.EditField
        ki_yEditFieldLabel              matlab.ui.control.Label
        ki_y                            matlab.ui.control.EditField
        ki_zEditFieldLabel              matlab.ui.control.Label
        ki_z                            matlab.ui.control.EditField
        Tab_5                           matlab.ui.container.Tab
        kp_phiLabel                     matlab.ui.control.Label
        kp_phi                          matlab.ui.control.EditField
        kd_phiLabel                     matlab.ui.control.Label
        kd_phi                          matlab.ui.control.EditField
        kp_thetaLabel                   matlab.ui.control.Label
        kp_theta                        matlab.ui.control.EditField
        kd_thetaLabel                   matlab.ui.control.Label
        kd_theta                        matlab.ui.control.EditField
        kp_psiLabel                     matlab.ui.control.Label
        kp_psi                          matlab.ui.control.EditField
        kd_psiLabel                     matlab.ui.control.Label
        kd_psi                          matlab.ui.control.EditField
        use_default                     matlab.ui.control.Button
        clear_all                       matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        HoverTab                        matlab.ui.container.Tab
        start_exp1                      matlab.ui.control.Button
        XmLabel                         matlab.ui.control.Label
        x_des                           matlab.ui.control.NumericEditField
        YmLabel_3                       matlab.ui.control.Label
        y_des                           matlab.ui.control.NumericEditField
        ZmLabel                         matlab.ui.control.Label
        z_des                           matlab.ui.control.NumericEditField
        sLabel_2                        matlab.ui.control.Label
        simtime                         matlab.ui.control.NumericEditField
        Label_13                        matlab.ui.control.Label
        TrackingTab                     matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        kct_pEditFieldLabel             matlab.ui.control.Label
        kct_p                           matlab.ui.control.NumericEditField
        kct_iEditFieldLabel             matlab.ui.control.Label
        kct_i                           matlab.ui.control.NumericEditField
        kct_dEditFieldLabel             matlab.ui.control.Label
        kct_d                           matlab.ui.control.NumericEditField
        kat_pEditFieldLabel             matlab.ui.control.Label
        kat_p                           matlab.ui.control.NumericEditField
        kat_iEditFieldLabel             matlab.ui.control.Label
        kat_i                           matlab.ui.control.NumericEditField
        exp2_default                    matlab.ui.control.Button
        x1y1z1v1x2y2z2v2EditFieldLabel  matlab.ui.control.Label
        exp2_state_track                matlab.ui.control.EditField
        start_exp2                      matlab.ui.control.Button
        sLabel                          matlab.ui.control.Label
        simtime_2                       matlab.ui.control.NumericEditField
        Formation_linearTab             matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        exp3_default                    matlab.ui.control.Button
        kct_pEditFieldLabel_2           matlab.ui.control.Label
        kct_p_2                         matlab.ui.control.NumericEditField
        kct_iEditFieldLabel_2           matlab.ui.control.Label
        kct_i_2                         matlab.ui.control.NumericEditField
        kct_dEditFieldLabel_2           matlab.ui.control.Label
        kct_d_2                         matlab.ui.control.NumericEditField
        kat_pEditFieldLabel_2           matlab.ui.control.Label
        kat_p_2                         matlab.ui.control.NumericEditField
        kat_iEditFieldLabel_2           matlab.ui.control.Label
        kat_i_2                         matlab.ui.control.NumericEditField
        x1y1z1v1x2y2z2v2EditFieldLabel_2  matlab.ui.control.Label
        exp3_state_track                matlab.ui.control.EditField
        sLabel_3                        matlab.ui.control.Label
        simtime_3                       matlab.ui.control.NumericEditField
        RmLabel                         matlab.ui.control.Label
        R                               matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        y1_init                         matlab.ui.control.NumericEditField
        YmLabel                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        y2_init                         matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        y3_init                         matlab.ui.control.NumericEditField
        start_exp3                      matlab.ui.control.Button
        Formation_circleTab             matlab.ui.container.Tab
        Label_9                         matlab.ui.control.Label
        exp4_default                    matlab.ui.control.Button
        YmLabel_2                       matlab.ui.control.Label
        start_exp4                      matlab.ui.control.Button
        kct_pEditFieldLabel_3           matlab.ui.control.Label
        kct_p_3                         matlab.ui.control.NumericEditField
        kct_iEditFieldLabel_3           matlab.ui.control.Label
        kct_i_3                         matlab.ui.control.NumericEditField
        kct_dEditFieldLabel_3           matlab.ui.control.Label
        kct_d_3                         matlab.ui.control.NumericEditField
        kat_pEditFieldLabel_3           matlab.ui.control.Label
        kat_p_3                         matlab.ui.control.NumericEditField
        kat_iEditFieldLabel_3           matlab.ui.control.Label
        kat_i_3                         matlab.ui.control.NumericEditField
        xyzRvLabel                      matlab.ui.control.Label
        exp4_state_track                matlab.ui.control.EditField
        sLabel_4                        matlab.ui.control.Label
        simtime_4                       matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        y1_init_2                       matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        y2_init_2                       matlab.ui.control.NumericEditField
        Label_12                        matlab.ui.control.Label
        y3_init_2                       matlab.ui.control.NumericEditField
        Panel_3                         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        fig_2d                          matlab.ui.control.UIAxes
        fig_3d                          matlab.ui.control.UIAxes
        simstate                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: use_default
        function use_defaultButtonPushed(app, event)
            % 机体参数
            app.m.Value = '0.5';
            app.g.Value = '9.8';
            app.L.Value = '0.2';
            
            app.Ixx.Value = '0.114';
            app.Iyy.Value = '0.114';
            app.Izz.Value = '0.158';
            
            % 电机参数
            app.k_F.Value = '6.11*10^(-8)*3600/(4*pi^2)';
            app.k_M.Value = '1.5*10^(-9)*3600/(4*pi^2)';
            app.k_m.Value = '20';
            
            % 位置环参数
            app.kp_x.Value = '0.5';
            app.ki_x.Value = '3e-4';
            app.kd_x.Value = '1.0';
            app.kp_y.Value = '0.5';
            app.ki_y.Value = '3e-4';
            app.kd_y.Value = '1';
            app.kp_z.Value = '1.2';
            app.ki_z.Value = '1e-6';
            app.kd_z.Value = '2';
            
            % 姿态环参数
            app.kp_phi.Value = '2000';
            app.kd_phi.Value = '4000';
            app.kp_theta.Value = '2000';
            app.kd_theta.Value = '4000';
            app.kp_psi.Value = '800';
            app.kd_psi.Value = '4000';
            
        end

        % Button pushed function: clear_all
        function clear_allButtonPushed(app, event)
            % 机体参数
            app.m.Value = '';
            app.g.Value = '';
            app.L.Value = '';
            
            app.Ixx.Value = '';
            app.Iyy.Value = '';
            app.Izz.Value = '';
            
            % 电机参数
            app.k_F.Value = '';
            app.k_M.Value = '';
            app.k_m.Value = '';
            
            % 位置环参数
            app.kp_x.Value = '';
            app.ki_x.Value = '';
            app.kd_x.Value = '';
            app.kp_y.Value = '';
            app.ki_y.Value = '';
            app.kd_y.Value = '';
            app.kp_z.Value = '';
            app.ki_z.Value = '';
            app.kd_z.Value = '';
            
            % 姿态环参数
            app.kp_phi.Value = '';
            app.kd_phi.Value = '';
            app.kp_theta.Value = '';
            app.kd_theta.Value = '';
            app.kp_psi.Value = '';
            app.kd_psi.Value = '';
        end

        % Button pushed function: exp2_default
        function exp2_defaultButtonPushed(app, event)
            app.kct_p.Value = 1;
            app.kct_i.Value = 0.05;
            app.kct_d.Value = 1;
            app.kat_p.Value = 1;
            app.kat_i.Value = 0.05;
        end

        % Button pushed function: exp3_default
        function exp3_defaultButtonPushed(app, event)
            app.kct_p_2.Value = 1;
            app.kct_i_2.Value = 0.05;
            app.kct_d_2.Value = 1;
            app.kat_p_2.Value = 1;
            app.kat_i_2.Value = 0.05;
        end

        % Button pushed function: exp4_default
        function exp4_defaultButtonPushed(app, event)
            app.kct_p_3.Value = 1;
            app.kct_i_3.Value = 0.05;
            app.kct_d_3.Value = 1;
            app.kat_p_3.Value = 1;
            app.kat_i_3.Value = 0.05;
        end

        % Button pushed function: start_exp1
        function start_exp1ButtonPushed(app, event)
            app.simstate.Text = 'Running simulation ... ';
            
            % 模型参数
            % 机体参数
            m = str2num(app.m.Value);
            g = str2num(app.g.Value);
            L = str2num(app.L.Value);
            
            Ixx = str2num(app.Ixx.Value);
            Iyy = str2num(app.Iyy.Value);
            Izz = str2num(app.Izz.Value);
            
            % 电机参数
            k_F = str2num(app.k_F.Value);
            k_M = str2num(app.k_M.Value);
            k_m = str2num(app.k_m.Value);
            
            % 位置环参数
            kp_x = str2num(app.kp_x.Value);
            ki_x = str2num(app.ki_x.Value);
            kd_x = str2num(app.kd_x.Value);
            kp_y = str2num(app.kp_y.Value);
            ki_y = str2num(app.ki_y.Value);
            kd_y = str2num(app.kd_y.Value);
            kp_z = str2num(app.kp_z.Value);
            ki_z = str2num(app.ki_z.Value);
            kd_z = str2num(app.kd_z.Value);
            
            % 姿态环参数
            kp_phi = str2num(app.kp_phi.Value);
            kd_phi = str2num(app.kd_phi.Value);
            kp_theta = str2num(app.kp_theta.Value);
            kd_theta = str2num(app.kd_theta.Value);
            kp_psi = str2num(app.kp_psi.Value);
            kd_psi = str2num(app.kd_psi.Value);
            
            % 仿真任务参数
            p_des = [app.x_des.Value;app.y_des.Value;app.z_des.Value];
            simtime = app.simtime.Value;
            
            % 仿真设置
            options = simset('SrcWorkspace','current');
            
            % 运行仿真并收取结果
            h = sim('exp1_hover',simtime,options);
            t = h.tout;
            
            % 画图
            cla(app.fig_2d);
            plot(app.fig_2d,t,h.position(:,1),t,h.position(:,2),t,h.position(:,3));
            xlabel(app.fig_2d,'t/s'); ylabel(app.fig_2d,'Position/m');
            legend(app.fig_2d,'X','Y','Z');
            grid(app.fig_2d,'on');
            
            % 3D画图
            
            addpath(genpath('3Dplot_fcns'));
            draw_quad_traj_by_app(h.position(:,1),h.position(:,2),h.position(:,3),...
                h.velocity(:,1),h.velocity(:,2),h.velocity(:,3),...
                h.angle(:,1),h.angle(:,2),h.angle(:,3),0,1,app);
            
            app.simstate.Text = 'Finished!';
        end

        % Button pushed function: start_exp2
        function start_exp2ButtonPushed(app, event)
            app.simstate.Text = 'Running simulation ... ';
            
            % 模型参数
            % 机体参数
            m = str2num(app.m.Value);
            g = str2num(app.g.Value);
            L = str2num(app.L.Value);
            
            Ixx = str2num(app.Ixx.Value);
            Iyy = str2num(app.Iyy.Value);
            Izz = str2num(app.Izz.Value);
            
            % 电机参数
            k_F = str2num(app.k_F.Value);
            k_M = str2num(app.k_M.Value);
            k_m = str2num(app.k_m.Value);
            
            % 位置环参数
            kp_x = str2num(app.kp_x.Value);
            ki_x = str2num(app.ki_x.Value);
            kd_x = str2num(app.kd_x.Value);
            kp_y = str2num(app.kp_y.Value);
            ki_y = str2num(app.ki_y.Value);
            kd_y = str2num(app.kd_y.Value);
            kp_z = str2num(app.kp_z.Value);
            ki_z = str2num(app.ki_z.Value);
            kd_z = str2num(app.kd_z.Value);
            
            % 姿态环参数
            kp_phi = str2num(app.kp_phi.Value);
            kd_phi = str2num(app.kd_phi.Value);
            kp_theta = str2num(app.kp_theta.Value);
            kd_theta = str2num(app.kd_theta.Value);
            kp_psi = str2num(app.kp_psi.Value);
            kd_psi = str2num(app.kd_psi.Value);
            
            % 轨迹跟踪器参数
            kct_p = app.kct_p.Value;
            kct_i = app.kct_i.Value;
            kct_d = app.kct_d.Value;
            kat_p = app.kat_p.Value;
            kat_i = app.kat_i.Value;
            
            % 仿真任务参数
            state_track = str2num(app.exp2_state_track.Value);
            simtime = app.simtime_2.Value;
            
            % 仿真设置
            options = simset('SrcWorkspace','current');
            
            % 运行仿真并收取结果
            h = sim('exp2_tracking',simtime,options);
            t = h.tout;
            
            % 画图
            cla(app.fig_2d);
            plot(app.fig_2d,t,h.position(:,1),t,h.position(:,2),t,h.position(:,3));
            xlabel(app.fig_2d,'t/s'); ylabel(app.fig_2d,'Position/m');
            legend(app.fig_2d,'X','Y','Z');
            grid(app.fig_2d,'on');
            
            % 3D画图
            addpath(genpath('3Dplot_fcns'));
            draw_quad_traj_by_app(h.position(:,1),h.position(:,2),h.position(:,3),...
                h.velocity(:,1),h.velocity(:,2),h.velocity(:,3),...
                h.angle(:,1),h.angle(:,2),h.angle(:,3),0,1,app);
            
            app.simstate.Text = 'Finished!';
        end

        % Button pushed function: start_exp3
        function start_exp3ButtonPushed(app, event)
            app.simstate.Text = 'Running simulation ... ';
            
            % 模型参数
            % 机体参数
            m = str2num(app.m.Value);
            g = str2num(app.g.Value);
            L = str2num(app.L.Value);
            
            Ixx = str2num(app.Ixx.Value);
            Iyy = str2num(app.Iyy.Value);
            Izz = str2num(app.Izz.Value);
            
            % 电机参数
            k_F = str2num(app.k_F.Value);
            k_M = str2num(app.k_M.Value);
            k_m = str2num(app.k_m.Value);
            
            % 位置环参数
            kp_x = str2num(app.kp_x.Value);
            ki_x = str2num(app.ki_x.Value);
            kd_x = str2num(app.kd_x.Value);
            kp_y = str2num(app.kp_y.Value);
            ki_y = str2num(app.ki_y.Value);
            kd_y = str2num(app.kd_y.Value);
            kp_z = str2num(app.kp_z.Value);
            ki_z = str2num(app.ki_z.Value);
            kd_z = str2num(app.kd_z.Value);
            
            % 姿态环参数
            kp_phi = str2num(app.kp_phi.Value);
            kd_phi = str2num(app.kd_phi.Value);
            kp_theta = str2num(app.kp_theta.Value);
            kd_theta = str2num(app.kd_theta.Value);
            kp_psi = str2num(app.kp_psi.Value);
            kd_psi = str2num(app.kd_psi.Value);
            
            % 轨迹跟踪器参数
            kct_p = app.kct_p_2.Value;
            kct_i = app.kct_i_2.Value;
            kct_d = app.kct_d_2.Value;
            kat_p = app.kat_p_2.Value;
            kat_i = app.kat_i_2.Value;
            
            % 仿真任务参数
            state_track = str2num(app.exp3_state_track.Value);
            simtime = app.simtime_3.Value;
            R = app.R.Value;
            y1_init = app.y1_init.Value;
            y2_init = app.y2_init.Value;
            y3_init = app.y3_init.Value;
            threshold = 2; % 航点到达判定阈值
            
            % 仿真设置
            options = simset('SrcWorkspace','current');
            
            % 运行仿真并收取结果
            h = sim('exp3_1_formation_linear.slx',simtime,options);
            t = h.tout;
            quad1.position = h.quad1(:,1:3);
            quad1.velocity = h.quad1(:,4:6);
            quad1.angle = h.quad1(:,7:9);
            
            quad2.position = h.quad2(:,1:3);
            quad2.velocity = h.quad2(:,4:6);
            quad2.angle = h.quad2(:,7:9);
            
            quad3.position = h.quad3(:,1:3);
            quad3.velocity = h.quad3(:,4:6);
            quad3.angle = h.quad3(:,7:9);
            
            % 画图
            cla(app.fig_2d);
            
            % 2D画图
            plot(app.fig_2d,quad1.position(:,1),quad1.position(:,2),'r',...
                quad2.position(:,1),quad2.position(:,2),'g',...
                quad3.position(:,1),quad3.position(:,2),'b');
            xlabel(app.fig_2d,'X/m'); ylabel(app.fig_2d,'Y/m');
            legend(app.fig_2d,'quad1','quad2','quad3');
            grid(app.fig_2d,'on');
            axis(app.fig_2d,'equal');
            
            % 动态绘图
            cla(app.fig_3d);
            for i = 1:10:size(t,1)
                scatter3(app.fig_3d,quad1.position(i,1),quad1.position(i,2),quad1.position(i,3),'r'); hold(app.fig_3d,'on');
                scatter3(app.fig_3d,quad2.position(i,1),quad2.position(i,2),quad2.position(i,3),'g'); hold(app.fig_3d,'on');
                scatter3(app.fig_3d,quad3.position(i,1),quad3.position(i,2),quad3.position(i,3),'b'); hold(app.fig_3d,'on');
                xlabel(app.fig_3d,'X/m'); ylabel(app.fig_3d,'Y/m'); zlabel(app.fig_3d,'Z/m');
                axis(app.fig_3d,'equal');
                pause(0.001);
            end
            
            app.simstate.Text = 'Finished!';
        end

        % Button pushed function: start_exp4
        function start_exp4ButtonPushed(app, event)
            app.simstate.Text = 'Running simulation ... ';
            
            % 模型参数
            % 机体参数
            m = str2num(app.m.Value);
            g = str2num(app.g.Value);
            L = str2num(app.L.Value);
            
            Ixx = str2num(app.Ixx.Value);
            Iyy = str2num(app.Iyy.Value);
            Izz = str2num(app.Izz.Value);
            
            % 电机参数
            k_F = str2num(app.k_F.Value);
            k_M = str2num(app.k_M.Value);
            k_m = str2num(app.k_m.Value);
            
            % 位置环参数
            kp_x = str2num(app.kp_x.Value);
            ki_x = str2num(app.ki_x.Value);
            kd_x = str2num(app.kd_x.Value);
            kp_y = str2num(app.kp_y.Value);
            ki_y = str2num(app.ki_y.Value);
            kd_y = str2num(app.kd_y.Value);
            kp_z = str2num(app.kp_z.Value);
            ki_z = str2num(app.ki_z.Value);
            kd_z = str2num(app.kd_z.Value);
            
            % 姿态环参数
            kp_phi = str2num(app.kp_phi.Value);
            kd_phi = str2num(app.kd_phi.Value);
            kp_theta = str2num(app.kp_theta.Value);
            kd_theta = str2num(app.kd_theta.Value);
            kp_psi = str2num(app.kp_psi.Value);
            kd_psi = str2num(app.kd_psi.Value);
            
            % 轨迹跟踪器参数
            kct_p = app.kct_p_3.Value;
            kct_i = app.kct_i_3.Value;
            kct_d = app.kct_d_3.Value;
            kat_p = app.kat_p_3.Value;
            kat_i = app.kat_i_3.Value;
            
            % 仿真任务参数
            formation = str2num(app.exp4_state_track.Value);
            simtime = app.simtime_4.Value;
            y1_init = app.y1_init_2.Value;
            y2_init = app.y2_init_2.Value;
            y3_init = app.y3_init_2.Value;
            threshold = 2; % 航点到达判定阈值
            
            % 仿真设置
            options = simset('SrcWorkspace','current');
            
            % 运行仿真并收取结果
            h = sim('exp3_2_formation_circle.slx',simtime,options);
            t = h.tout;
            quad1.position = h.quad1(:,1:3);
            quad1.velocity = h.quad1(:,4:6);
            quad1.angle = h.quad1(:,7:9);
            
            quad2.position = h.quad2(:,1:3);
            quad2.velocity = h.quad2(:,4:6);
            quad2.angle = h.quad2(:,7:9);
            
            quad3.position = h.quad3(:,1:3);
            quad3.velocity = h.quad3(:,4:6);
            quad3.angle = h.quad3(:,7:9);
            
            % 画图
            
            % 2D画图
            cla(app.fig_2d);
            plot(app.fig_2d,quad1.position(:,1),quad1.position(:,2),'r',...
                quad2.position(:,1),quad2.position(:,2),'g',...
                quad3.position(:,1),quad3.position(:,2),'b');
            xlabel(app.fig_2d,'X/m'); ylabel(app.fig_2d,'Y/m');
            legend(app.fig_2d,'quad1','quad2','quad3');
            grid(app.fig_2d,'on');
            axis(app.fig_2d,'equal');
            
            % 动态绘图
            cla(app.fig_3d);
            
            x = formation(1);
            y = formation(2);
            R = formation(4);
            rectangle(app.fig_3d,'Position',[x-R,y-R,2*R,2*R],'Curvature',[1,1],'linewidth',1,'linestyle','--'); hold(app.fig_3d,'on');
            
            rectangle(app.fig_3d,'Position',[x-1.5*R,y-1.5*R,3*R,3*R],'Curvature',[1,1],'edgecolor','white'); hold(app.fig_3d,'on');
            
            xlabel(app.fig_3d,'X/m'); ylabel(app.fig_3d,'Y/m');
            
            for i = 1:5:size(t,1)
                p1(i) = scatter(app.fig_3d,quad1.position(i,1),quad1.position(i,2),'r'); hold(app.fig_3d,'on');
                p2(i) = scatter(app.fig_3d,quad2.position(i,1),quad2.position(i,2),'g'); hold(app.fig_3d,'on');
                p3(i) = scatter(app.fig_3d,quad3.position(i,1),quad3.position(i,2),'b'); hold(app.fig_3d,'on');
                xlabel(app.fig_3d,'X/m'); ylabel(app.fig_3d,'Y/m');
                axis(app.fig_3d,'equal');
                pause(0.001);
                if i > 30
                    delete(p1(i-30)); delete(p2(i-30)); delete(p3(i-30));
                end
            end
            
            app.simstate.Text = 'Finished!';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AirspeedIndicatorLabel = uilabel(app.UIFigure);
            app.AirspeedIndicatorLabel.Text = 'Airspeed Indicator';
            app.speed_meter = uiaeroairspeed(app.UIFigure);
            app.ArtificialHorizonLabel = uilabel(app.UIFigure);
            app.ArtificialHorizonLabel.Text = 'ArtificialHorizon';
            app.horizon_meter = uiaerohorizon(app.UIFigure);
            app.HeadingIndicatorLabel = uilabel(app.UIFigure);
            app.HeadingIndicatorLabel.Text = 'HeadingIndicator';
            app.heading_meter = uiaeroheading(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '模型参数';
            app.TabGroup2 = uitabgroup(app.Panel);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '机体参数';
            app.mkgLabel = uilabel(app.Tab_2);
            app.mkgLabel.Text = '质量m (kg)';
            app.m = uieditfield(app.Tab_2, 'text');
            app.gkgs2Label = uilabel(app.Tab_2);
            app.gkgs2Label.Text = '重力加速度g (kg/s^2)';
            app.g = uieditfield(app.Tab_2, 'text');
            app.LmLabel = uilabel(app.Tab_2);
            app.LmLabel.Text = '机臂长度L (m)';
            app.L = uieditfield(app.Tab_2, 'text');
            app.IxxLabel = uilabel(app.Tab_2);
            app.IxxLabel.Text = 'Ixx';
            app.Ixx = uieditfield(app.Tab_2, 'text');
            app.IyyLabel = uilabel(app.Tab_2);
            app.IyyLabel.Text = 'Iyy';
            app.Iyy = uieditfield(app.Tab_2, 'text');
            app.IzzLabel = uilabel(app.Tab_2);
            app.IzzLabel.Text = 'Izz';
            app.Izz = uieditfield(app.Tab_2, 'text');
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '电机参数';
            app.k_FEditFieldLabel = uilabel(app.Tab_3);
            app.k_FEditFieldLabel.Text = 'k_F';
            app.k_F = uieditfield(app.Tab_3, 'text');
            app.k_MEditFieldLabel = uilabel(app.Tab_3);
            app.k_MEditFieldLabel.Text = 'k_M';
            app.k_M = uieditfield(app.Tab_3, 'text');
            app.k_mEditFieldLabel = uilabel(app.Tab_3);
            app.k_mEditFieldLabel.Text = 'k_m';
            app.k_m = uieditfield(app.Tab_3, 'text');
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '位置环参数';
            app.kp_xEditFieldLabel = uilabel(app.Tab_4);
            app.kp_xEditFieldLabel.Text = 'kp_x';
            app.kp_x = uieditfield(app.Tab_4, 'text');
            app.ki_xEditFieldLabel = uilabel(app.Tab_4);
            app.ki_xEditFieldLabel.Text = 'ki_x';
            app.ki_x = uieditfield(app.Tab_4, 'text');
            app.kd_xEditFieldLabel = uilabel(app.Tab_4);
            app.kd_xEditFieldLabel.Text = 'kd_x';
            app.kd_x = uieditfield(app.Tab_4, 'text');
            app.kp_yEditFieldLabel = uilabel(app.Tab_4);
            app.kp_yEditFieldLabel.Text = 'kp_y';
            app.kp_y = uieditfield(app.Tab_4, 'text');
            app.kd_yEditFieldLabel = uilabel(app.Tab_4);
            app.kd_yEditFieldLabel.Text = 'kd_y';
            app.kd_y = uieditfield(app.Tab_4, 'text');
            app.kp_zEditFieldLabel = uilabel(app.Tab_4);
            app.kp_zEditFieldLabel.Text = 'kp_z';
            app.kp_z = uieditfield(app.Tab_4, 'text');
            app.kd_zEditFieldLabel = uilabel(app.Tab_4);
            app.kd_zEditFieldLabel.Text = 'kd_z';
            app.kd_z = uieditfield(app.Tab_4, 'text');
            app.ki_yEditFieldLabel = uilabel(app.Tab_4);
            app.ki_yEditFieldLabel.Text = 'ki_y';
            app.ki_y = uieditfield(app.Tab_4, 'text');
            app.ki_zEditFieldLabel = uilabel(app.Tab_4);
            app.ki_zEditFieldLabel.Text = 'ki_z';
            app.ki_z = uieditfield(app.Tab_4, 'text');
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '姿态环参数';
            app.kp_phiLabel = uilabel(app.Tab_5);
            app.kp_phiLabel.Text = 'kp_phi';
            app.kp_phi = uieditfield(app.Tab_5, 'text');
            app.kd_phiLabel = uilabel(app.Tab_5);
            app.kd_phiLabel.Text = 'kd_phi';
            app.kd_phi = uieditfield(app.Tab_5, 'text');
            app.kp_thetaLabel = uilabel(app.Tab_5);
            app.kp_thetaLabel.Text = 'kp_theta';
            app.kp_theta = uieditfield(app.Tab_5, 'text');
            app.kd_thetaLabel = uilabel(app.Tab_5);
            app.kd_thetaLabel.Text = 'kd_theta';
            app.kd_theta = uieditfield(app.Tab_5, 'text');
            app.kp_psiLabel = uilabel(app.Tab_5);
            app.kp_psiLabel.Text = 'kp_psi';
            app.kp_psi = uieditfield(app.Tab_5, 'text');
            app.kd_psiLabel = uilabel(app.Tab_5);
            app.kd_psiLabel.Text = 'kd_psi';
            app.kd_psi = uieditfield(app.Tab_5, 'text');
            app.use_default = uibutton(app.Panel, 'push');
            app.use_default.ButtonPushedFcn = createCallbackFcn(app, @use_defaultButtonPushed, true);
            app.use_default.Text = '默认参数';
            app.clear_all = uibutton(app.Panel, 'push');
            app.clear_all.ButtonPushedFcn = createCallbackFcn(app, @clear_allButtonPushed, true);
            app.clear_all.Text = '全部清除';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '仿真任务';
            app.TabGroup = uitabgroup(app.Panel_2);
            app.HoverTab = uitab(app.TabGroup);
            app.HoverTab.Title = 'Hover';
            app.start_exp1 = uibutton(app.HoverTab, 'push');
            app.start_exp1.ButtonPushedFcn = createCallbackFcn(app, @start_exp1ButtonPushed, true);
            app.start_exp1.Text = '开始仿真';
            app.XmLabel = uilabel(app.HoverTab);
            app.XmLabel.Text = '期望X坐标 (m)';
            app.x_des = uieditfield(app.HoverTab, 'numeric');
            app.x_des.Value = 10;
            app.YmLabel_3 = uilabel(app.HoverTab);
            app.YmLabel_3.Text = '期望Y坐标 (m)';
            app.y_des = uieditfield(app.HoverTab, 'numeric');
            app.y_des.Value = 10;
            app.ZmLabel = uilabel(app.HoverTab);
            app.ZmLabel.Text = '期望Z坐标 (m)';
            app.z_des = uieditfield(app.HoverTab, 'numeric');
            app.z_des.Value = -10;
            app.sLabel_2 = uilabel(app.HoverTab);
            app.sLabel_2.Text = '仿真时间 (s)';
            app.simtime = uieditfield(app.HoverTab, 'numeric');
            app.simtime.Value = 20;
            app.Label_13 = uilabel(app.HoverTab);
            app.Label_13.Text = '期望坐标（北-东-地坐标系）';
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.Label_2 = uilabel(app.TrackingTab);
            app.Label_2.Text = '轨迹跟踪器参数';
            app.kct_pEditFieldLabel = uilabel(app.TrackingTab);
            app.kct_pEditFieldLabel.Text = 'kct_p';
            app.kct_p = uieditfield(app.TrackingTab, 'numeric');
            app.kct_p.Value = 1;
            app.kct_iEditFieldLabel = uilabel(app.TrackingTab);
            app.kct_iEditFieldLabel.Text = 'kct_i';
            app.kct_i = uieditfield(app.TrackingTab, 'numeric');
            app.kct_i.Value = 0.05;
            app.kct_dEditFieldLabel = uilabel(app.TrackingTab);
            app.kct_dEditFieldLabel.Text = 'kct_d';
            app.kct_d = uieditfield(app.TrackingTab, 'numeric');
            app.kct_d.Value = 1;
            app.kat_pEditFieldLabel = uilabel(app.TrackingTab);
            app.kat_pEditFieldLabel.Text = 'kat_p';
            app.kat_p = uieditfield(app.TrackingTab, 'numeric');
            app.kat_p.Value = 1;
            app.kat_iEditFieldLabel = uilabel(app.TrackingTab);
            app.kat_iEditFieldLabel.Text = 'kat_i';
            app.kat_i = uieditfield(app.TrackingTab, 'numeric');
            app.kat_i.Value = 0.05;
            app.exp2_default = uibutton(app.TrackingTab, 'push');
            app.exp2_default.ButtonPushedFcn = createCallbackFcn(app, @exp2_defaultButtonPushed, true);
            app.exp2_default.Text = '默认参数';
            app.x1y1z1v1x2y2z2v2EditFieldLabel = uilabel(app.TrackingTab);
            app.x1y1z1v1x2y2z2v2EditFieldLabel.Text = '航路 [x1,y1,z1,v1;x2,y2,z2,v2;...]';
            app.exp2_state_track = uieditfield(app.TrackingTab, 'text');
            app.exp2_state_track.Value = '[0,0,0,0;10,0,-10,1;10,10,-10,1;0,10,-10,1;0,0,0,1]';
            app.start_exp2 = uibutton(app.TrackingTab, 'push');
            app.start_exp2.ButtonPushedFcn = createCallbackFcn(app, @start_exp2ButtonPushed, true);
            app.start_exp2.Text = '开始仿真';
            app.sLabel = uilabel(app.TrackingTab);
            app.sLabel.Text = '仿真时间 (s)';
            app.simtime_2 = uieditfield(app.TrackingTab, 'numeric');
            app.simtime_2.Value = 150;
            app.Formation_linearTab = uitab(app.TabGroup);
            app.Formation_linearTab.Title = 'Formation_linear';
            app.Label_4 = uilabel(app.Formation_linearTab);
            app.Label_4.Text = '轨迹跟踪器参数';
            app.exp3_default = uibutton(app.Formation_linearTab, 'push');
            app.exp3_default.ButtonPushedFcn = createCallbackFcn(app, @exp3_defaultButtonPushed, true);
            app.exp3_default.Text = '默认参数';
            app.kct_pEditFieldLabel_2 = uilabel(app.Formation_linearTab);
            app.kct_pEditFieldLabel_2.Text = 'kct_p';
            app.kct_p_2 = uieditfield(app.Formation_linearTab, 'numeric');
            app.kct_p_2.Value = 1;
            app.kct_iEditFieldLabel_2 = uilabel(app.Formation_linearTab);
            app.kct_iEditFieldLabel_2.Text = 'kct_i';
            app.kct_i_2 = uieditfield(app.Formation_linearTab, 'numeric');
            app.kct_i_2.Value = 0.05;
            app.kct_dEditFieldLabel_2 = uilabel(app.Formation_linearTab);
            app.kct_dEditFieldLabel_2.Text = 'kct_d';
            app.kct_d_2 = uieditfield(app.Formation_linearTab, 'numeric');
            app.kct_d_2.Value = 1;
            app.kat_pEditFieldLabel_2 = uilabel(app.Formation_linearTab);
            app.kat_pEditFieldLabel_2.Text = 'kat_p';
            app.kat_p_2 = uieditfield(app.Formation_linearTab, 'numeric');
            app.kat_p_2.Value = 1;
            app.kat_iEditFieldLabel_2 = uilabel(app.Formation_linearTab);
            app.kat_iEditFieldLabel_2.Text = 'kat_i';
            app.kat_i_2 = uieditfield(app.Formation_linearTab, 'numeric');
            app.kat_i_2.Value = 0.05;
            app.x1y1z1v1x2y2z2v2EditFieldLabel_2 = uilabel(app.Formation_linearTab);
            app.x1y1z1v1x2y2z2v2EditFieldLabel_2.Text = '航路 [x1,y1,z1,v1;x2,y2,z2,v2;...]';
            app.exp3_state_track = uieditfield(app.Formation_linearTab, 'text');
            app.exp3_state_track.Value = '[0,0,0,0;10,0,-10,1;10,10,-10,1;0,10,-10,1;0,0,0,1]';
            app.sLabel_3 = uilabel(app.Formation_linearTab);
            app.sLabel_3.Text = '仿真时间 (s)';
            app.simtime_3 = uieditfield(app.Formation_linearTab, 'numeric');
            app.simtime_3.Value = 120;
            app.RmLabel = uilabel(app.Formation_linearTab);
            app.RmLabel.Text = '编队间距R (m)';
            app.R = uieditfield(app.Formation_linearTab, 'numeric');
            app.R.Value = 0.5;
            app.Label_6 = uilabel(app.Formation_linearTab);
            app.Label_6.Text = '1号机';
            app.y1_init = uieditfield(app.Formation_linearTab, 'numeric');
            app.YmLabel = uilabel(app.Formation_linearTab);
            app.YmLabel.Text = '各无人机初始Y坐标 (m)';
            app.Label_7 = uilabel(app.Formation_linearTab);
            app.Label_7.Text = '2号机';
            app.y2_init = uieditfield(app.Formation_linearTab, 'numeric');
            app.y2_init.Value = 0.5;
            app.Label_8 = uilabel(app.Formation_linearTab);
            app.Label_8.Text = '3号机';
            app.y3_init = uieditfield(app.Formation_linearTab, 'numeric');
            app.y3_init.Value = -0.5;
            app.start_exp3 = uibutton(app.Formation_linearTab, 'push');
            app.start_exp3.ButtonPushedFcn = createCallbackFcn(app, @start_exp3ButtonPushed, true);
            app.start_exp3.Text = '开始仿真';
            app.Formation_circleTab = uitab(app.TabGroup);
            app.Formation_circleTab.Title = 'Formation_circle';
            app.Label_9 = uilabel(app.Formation_circleTab);
            app.Label_9.Text = '轨迹跟踪器参数';
            app.exp4_default = uibutton(app.Formation_circleTab, 'push');
            app.exp4_default.ButtonPushedFcn = createCallbackFcn(app, @exp4_defaultButtonPushed, true);
            app.exp4_default.Text = '默认参数';
            app.YmLabel_2 = uilabel(app.Formation_circleTab);
            app.YmLabel_2.Text = '各无人机初始Y坐标 (m)';
            app.start_exp4 = uibutton(app.Formation_circleTab, 'push');
            app.start_exp4.ButtonPushedFcn = createCallbackFcn(app, @start_exp4ButtonPushed, true);
            app.start_exp4.Text = '开始仿真';
            app.kct_pEditFieldLabel_3 = uilabel(app.Formation_circleTab);
            app.kct_pEditFieldLabel_3.Text = 'kct_p';
            app.kct_p_3 = uieditfield(app.Formation_circleTab, 'numeric');
            app.kct_p_3.Value = 1;
            app.kct_iEditFieldLabel_3 = uilabel(app.Formation_circleTab);
            app.kct_iEditFieldLabel_3.Text = 'kct_i';
            app.kct_i_3 = uieditfield(app.Formation_circleTab, 'numeric');
            app.kct_i_3.Value = 0.05;
            app.kct_dEditFieldLabel_3 = uilabel(app.Formation_circleTab);
            app.kct_dEditFieldLabel_3.Text = 'kct_d';
            app.kct_d_3 = uieditfield(app.Formation_circleTab, 'numeric');
            app.kct_d_3.Value = 1;
            app.kat_pEditFieldLabel_3 = uilabel(app.Formation_circleTab);
            app.kat_pEditFieldLabel_3.Text = 'kat_p';
            app.kat_p_3 = uieditfield(app.Formation_circleTab, 'numeric');
            app.kat_p_3.Value = 1;
            app.kat_iEditFieldLabel_3 = uilabel(app.Formation_circleTab);
            app.kat_iEditFieldLabel_3.Text = 'kat_i';
            app.kat_i_3 = uieditfield(app.Formation_circleTab, 'numeric');
            app.kat_i_3.Value = 0.05;
            app.xyzRvLabel = uilabel(app.Formation_circleTab);
            app.xyzRvLabel.Text = '圆形编队参数：[圆心x, y, z，半径R，飞行速度v]';
            app.exp4_state_track = uieditfield(app.Formation_circleTab, 'text');
            app.exp4_state_track.Value = ' [0,0,-5,20,2]';
            app.sLabel_4 = uilabel(app.Formation_circleTab);
            app.sLabel_4.Text = '仿真时间 (s)';
            app.simtime_4 = uieditfield(app.Formation_circleTab, 'numeric');
            app.simtime_4.Value = 120;
            app.Label_10 = uilabel(app.Formation_circleTab);
            app.Label_10.Text = '1号机';
            app.y1_init_2 = uieditfield(app.Formation_circleTab, 'numeric');
            app.Label_11 = uilabel(app.Formation_circleTab);
            app.Label_11.Text = '2号机';
            app.y2_init_2 = uieditfield(app.Formation_circleTab, 'numeric');
            app.y2_init_2.Value = 5;
            app.Label_12 = uilabel(app.Formation_circleTab);
            app.Label_12.Text = '3号机';
            app.y3_init_2 = uieditfield(app.Formation_circleTab, 'numeric');
            app.y3_init_2.Value = -5;
            app.Panel_3 = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel_3);
            app.fig_2d = uiaxes(app.GridLayout);
            title(app.fig_2d, '位置响应曲线（北-东-地坐标系）')
            xlabel(app.fig_2d, 't/s')
            ylabel(app.fig_2d, 'Position/m')
            app.fig_3d = uiaxes(app.GridLayout);
            title(app.fig_3d, '动态模拟')
            app.simstate = uilabel(app.UIFigure);
            app.simstate.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuadSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end